classdef lab1 < matlab.apps.AppBase
    %LAB1 translate

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_8  matlab.ui.control.Button
        Button_7  matlab.ui.control.Button
        Button_6  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)

            % se=translate(strel(1),[100,100]);  
            % ResultImage=imdilate(InputImage,se);
            imread("bear.jpg", "jpg")
        end

        % Button pushed function: Button
        function onTranslate(app, event)
            app.img2 = imtranslate(app.img, [60, -100],"linear","FillValues",255)
            imshow(app.img2, 'parent', app.UIAxes2);
        end

        % Button pushed function: Button_2
        function onRotate(app, event)
            answer = inputdlg("请输入","旋转角度");

            app.img2 = imrotate(app.img, str2double(answer)); % 60
            imshow(app.img2, 'parent', app.UIAxes2);
        end

        % Button pushed function: Button_3
        function onPick(app, event)
            [file, path] = uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.img = imread(fullfile(path, file));
            imshow(app.img, 'parent', app.UIAxes);
        end

        % Button pushed function: Button_4
        function onRGB2Gray(app, event)
            app.img2 = rgb2gray(app.img);
            imshow(app.img2, 'parent', app.UIAxes2);
        end

        % Callback function
        function onRest(app, event)
            getappdata(1,"kk")
        end

        % Button pushed function: Button_5
        function onSave(app, event)
            % https://www.youtube.com/watch?v=oIJ_fmpSfaQ&t=1033s
            % imwrite(app.img2,"newbear.jpg","jpg")

            im=imread('image3.jpeg');
        end

        % Button pushed function: Button_6
        function onScale(app, event)
            answer = inputdlg("请输入","缩放比例");

            app.img2 = imresize(app.img, str2double(answer)); % 0.5
            imshow(app.img2, 'parent', app.UIAxes2);
        end

        % Button pushed function: Button_7
        function onCrop(app, event)
           app.img2 = imcrop(app.img, [0 0 500 500]) ;
           imshow(app.img2, 'parent', app.UIAxes2);
        end

        % Button pushed function: Button_8
        function onDialog(app, event)
            % msgbox("哈哈哈", "modal");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原图')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '修改')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @onTranslate, true);
            app.Button.Tag = 'translate';
            app.Button.Text = '平移';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @onRotate, true);
            app.Button_2.Tag = 'rotate';
            app.Button_2.Text = '旋转';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @onPick, true);
            app.Button_3.Text = '选择';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @onRGB2Gray, true);
            app.Button_4.Tag = 'rotate';
            app.Button_4.Text = '灰色';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @onSave, true);
            app.Button_5.Text = '保存';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @onScale, true);
            app.Button_6.Tag = 'rotate';
            app.Button_6.Text = '缩放';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @onCrop, true);
            app.Button_7.Tag = 'rotate';
            app.Button_7.Text = '裁剪';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @onDialog, true);
            app.Button_8.Tag = 'rotate';
            app.Button_8.Text = '对话框';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end