classdef lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function onPick(app, event)
            [file, path] = uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.img = imread(fullfile(path, file));
            imshow(app.img, 'parent', app.UIAxes);
        end

        % Button pushed function: Button_2
        function onRemoveNoise(app, event)
            % https://ww2.mathworks.cn/matlabcentral/answers/457760-how-do-i-remove-the-scattered-noise-from-my-image
            app.img2 = im2bw(app.img);
            figure(app.UIFigure);
            imshow(app.img2);
            im3 = bwareaopen(app.img2, 60);
            %..................^.. Change this value as per your requirements
            figure(app.UIFigure);
            imshow(im3, 'parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原图')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '修改')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @onPick, true);
            app.Button.Text = '选择图片';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @onRemoveNoise, true);
            app.Button_2.Text = '去除噪声';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end